classdef exploreSimulations < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
    end


    properties (Access = public)
        TileLayout matlab.graphics.layout.TiledChartLayout % Added property for TileLayout
        SecondLayout matlab.graphics.layout.TiledChartLayout % Added property for TileLayout
        ThirdLayout matlab.graphics.layout.TiledChartLayout
        t
        RightPanel matlab.ui.container.Panel
        PlotTitle matlab.ui.control.Label  % Label used to display the overall title above the plot panel
        TimeSlider matlab.ui.control.Slider  % New slider above SecondLayout
        TimeSliderLabel matlab.ui.control.Label
        DepthSlider matlab.ui.control.Slider  % New slider above SecondLayout
        DeptheSliderLabel matlab.ui.control.Label
        Button1
        Button2
        Button3
        PlotSelector matlab.ui.control.DropDown % Drop-down menu to select plot type
        Panel1 matlab.ui.container.Panel
        Panel2 matlab.ui.container.Panel
        Panel3 matlab.ui.container.Panel
        LastValidPlotType
    end


    methods (Access = private)
        %% Callback function to do when TimeSlider value change
        function updateTimeSliderSliderValue(app, slider, sim)
            timeValue = floor(app.TimeSlider.Value);

            % Update the label with current time
            app.TimeSliderLabel.Text = sprintf('Time: %d', timeValue);

            Bdepth = sum(sim.B(timeValue,:,:),3);
            iDepth = find(Bdepth == max(Bdepth));

            if isempty(app.SecondLayout) || ~isvalid(app.SecondLayout)
                return;
            end

            delete(allchild(app.SecondLayout)); % Clear the plots

            % Determine the plot type to use: current if valid, else fallback
            if ismember(app.PlotSelector.Value, sim.p.nameGroup)
                plotType = app.PlotSelector.Value;
                app.LastValidPlotType = plotType;  % Optional: refresh memory
            else
                plotType = app.LastValidPlotType;
            end

            plotWatercolumntest(sim, timeValue, [] ,[], plotType, app.SecondLayout, 'depthMax', 200);
        end

        %% Callback function to do when DepthSlider value change
        function updateDepthSliderSliderValue(app, slider, sim)
            depthValue = abs(slider.Value);  % Convert slider negative value to positive depth

            % Find the index where sim.z(i) <= depthValue < sim.z(i+1)
            idx = find(sim.z <= depthValue, 1, 'last');

            % If depthValue exceeds sim.z(end), cap to last index
            if isempty(idx)
                idx = 1;  % If above surface level
            elseif depthValue >= sim.z(end)
                idx = length(sim.z);  % Correctly pick the deepest layer
            end


            if isempty(app.ThirdLayout) || ~isvalid(app.ThirdLayout)
                return;
            end

            delete(allchild(app.ThirdLayout)); % Clear the plots

            % Example plot update call
            plotSizeSpectrumTest(sim, floor(app.TimeSlider.Value), idx,[],[],app.ThirdLayout);
            
        end


        %% Resize function
        function onResize(app)
            figPos = app.UIFigure.Position;
            figWidth = figPos(3);
            figHeight = figPos(4);

            margin = 10;
            numPanels = 3;
            rightPadding = 20;  % space between last panel and right edge
            leftPadding = 10;   % space before first panel

            % Calculate available width for panels (minus all margins and paddings)
            totalMargins = margin * (numPanels - 1);
            availableWidth = figWidth - leftPadding - rightPadding - totalMargins;
            tileW = availableWidth / numPanels;

            % Panel horizontal positions
            tileX1 = leftPadding;
            tileX2 = tileX1 + tileW + margin;
            tileX3 = tileX2 + tileW + margin;

            % Panel vertical size and normalized positions
            tileY = 0.1 * figHeight;
            tileH = 0.7 * figHeight;
            normalizedH = (tileH) / figHeight;
            normalizedW = (tileW) / figWidth;

            % Assign positions to panels (normalized)
            app.Panel1.Position = [tileX1 / figWidth, tileY / figHeight, normalizedW, normalizedH];
            app.Panel2.Position = [tileX2 / figWidth, tileY / figHeight, normalizedW, normalizedH];
            app.Panel3.Position = [tileX3 / figWidth, tileY / figHeight, normalizedW, normalizedH];

            % Update PlotSelector position
            if ~isempty(app.PlotSelector) && isvalid(app.PlotSelector)
                app.PlotSelector.Position = [tileX1, figHeight - 50, 150, 22];
            end
        end
        %% Update the plots when the PlotSelector value change.
        function updatePlot(app, event, sim)
            plotType = app.PlotSelector.Value;

            % If valid, update LastValidPlotType
            if ismember(plotType, sim.p.nameGroup)
                app.LastValidPlotType = plotType;
            end

            % Try plotting
            if ~isempty(app.TileLayout) && isvalid(app.TileLayout)
                plotWatercolumnTimeTest(sim, 'depthMax', 200, plotType, app.TileLayout);
            end
            if ~isempty(app.SecondLayout) && isvalid(app.SecondLayout) && ismember(plotType, sim.p.nameGroup)
                plotWatercolumntest(sim, floor(app.TimeSlider.Value), [], [], plotType, app.SecondLayout, 'depthMax', 200);
            end

            % Try resizing
            onResize(app);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, sim)
            app.UIFigure.AutoResizeChildren = 'off';
            app.UIFigure.SizeChangedFcn = @(src, event) onResize(app);
            % Window dimensions
            figPos = app.UIFigure.Position;
            figWidth = figPos(3);
            figHeight = figPos(4);
            % TileLayout dimensions and position in pixels
            tileW = 0.45 * figWidth;   % ~514 px
            tileH = 0.6 * figHeight;   % ~370 px
            tileX = 0.05 * figWidth ;   % Slightly shifted to the right (~50 px)
            tileY = 0.3 * figHeight;   % ~185 px
            % Set up the plot selector drop-down menu
            app.PlotSelector = uidropdown(app.UIFigure);

            app.PlotSelector.Items = [sim.p.nameGroup, sim.p.nameNutrientsLong];% Add Biomass if i have time 'Biomass'
            %app.PlotSelector.Items = {'Biomass','Nitrogen', 'Silicate', 'DOC'};  % List of plot types
            app.PlotSelector.ValueChangedFcn = @(src, event) updatePlot(app, event,sim);  % Callback function


            %% Create the first layout
            app.Panel1 = uipanel(app.UIFigure, 'Units', 'normalized');
            app.TileLayout = tiledlayout(app.Panel1, 1, 1);
    	    
            % Call the plotting function and pass the layout as an argument
            plotWatercolumnTimeTest(sim, 'depthMax', 200, app.PlotSelector.Value, app.TileLayout);

            %% Create the second layout
            app.Panel2 = uipanel(app.UIFigure, 'Units', 'normalized');

            % Create a grid layout in Panel2 with 3 rows: slider, label, plot
            panel2Grid = uigridlayout(app.Panel2, [3, 1]);
            panel2Grid.RowHeight = {40, 20, '1x'};  % Slider, Label, Plot
            panel2Grid.ColumnWidth = {'1x'};

            % Create the time slider
            app.TimeSlider = uislider(panel2Grid);
            app.TimeSlider.Layout.Row = 1;
            app.TimeSlider.Layout.Column = 1;
            app.TimeSlider.Limits = [1, length(sim.t)];
            app.TimeSlider.MajorTicks = [1, 365, 730, 1095, 1460, 1825];
            app.TimeSlider.Value = 1;  % Initial value
            app.TimeSlider.ValueChangedFcn = @(src, event) updateTimeSliderSliderValue(app, src, sim);

            % Create a label below the slider to display the time value
            app.TimeSliderLabel = uilabel(panel2Grid);
            app.TimeSliderLabel.Layout.Row = 2;
            app.TimeSliderLabel.Layout.Column = 1;
            app.TimeSliderLabel.HorizontalAlignment = 'center';
            app.TimeSliderLabel.Text = sprintf('Time: %d', app.TimeSlider.Value);

            % Create the plot panel below the label
            plotPanel = uipanel(panel2Grid);
            plotPanel.Layout.Row = 3;
            plotPanel.Layout.Column = 1;
            tiled = tiledlayout(plotPanel, 2, 1, ...
                'TileSpacing','compact','Padding','compact');
            app.SecondLayout = tiled;  % Store it for future plotting

            
            plotWatercolumntest(sim, floor(app.TimeSlider.Value), [] ,[], app.PlotSelector.Value, app.SecondLayout, 'depthMax', 200);
            %% Create the third layout
            app.Panel3=uipanel(app.UIFigure, 'Units', 'normalized');

            % Create a grid layout in Panel3 with 2 rows (slider + spacer/plot area)
            panel3Grid = uigridlayout(app.Panel3, [3, 1]);
            panel3Grid.RowHeight = {40,20, '1x'};
            panel3Grid.ColumnWidth = {'1x'};

            % Create a slider in the first row of the grid
            DepthSlider = uislider(panel3Grid);
            DepthSlider.Limits = [-710, 0];
            DepthSlider.Layout.Row = 1;
            DepthSlider.Layout.Column = 1;
            DepthSlider.Value = -710; % Set default value


            % Create a label below the slider
            DeptheSliderLabel = uilabel(panel3Grid);
            DeptheSliderLabel.Layout.Row = 2;
            DeptheSliderLabel.Layout.Column = 1;
            DeptheSliderLabel.HorizontalAlignment = 'center';
            DeptheSliderLabel.Text = sprintf('Value: %.0f', DepthSlider.Value);

            % Callback: update label when slider moves
            DepthSlider.ValueChangedFcn = @(src, event) updateDepthSliderSliderValue(app, src, sim);

            % Create an empty panel for future plots
            Panel3PlotPanel = uipanel(panel3Grid);
            Panel3PlotPanel.Layout.Row = 3;
            Panel3PlotPanel.Layout.Column = 1;

            tiled3 = tiledlayout(Panel3PlotPanel, 4, 1, ...
                'TileSpacing','compact','Padding','compact');
            app.ThirdLayout = tiled3;  % Store it for future plotting
            
            idx = find(sim.z <= abs(DepthSlider.Value), 1, 'last');
            
            % If depthValue exceeds sim.z(end), cap to last index
            if isempty(idx)
                idx = 1;  % If above surface level
            elseif abs(DepthSlider.Value) >= sim.z(end)
                idx = length(sim.z);  % Correctly pick the deepest layer
            end
            
            plotSizeSpectrumTest(sim, floor(app.TimeSlider.Value), idx,[],[],app.ThirdLayout);
            % Initialize last valid plot type
            if ismember(app.PlotSelector.Value, sim.p.nameGroup)
                app.LastValidPlotType = app.PlotSelector.Value;
            else
                app.LastValidPlotType = sim.p.nameGroup{1}; % fallback to a default
            end

            onResize(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = exploreSimulations(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end